classdef PF_IAIM_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_App              matlab.ui.Figure
        Panel_Visualizations      matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        Tab_3DVisualization       matlab.ui.container.Tab
        Panel_3DVisualization     matlab.ui.container.Panel
        Grid_3DVisualization      matlab.ui.container.GridLayout
        Panel_Damage4             matlab.ui.container.Panel
        Panel_Damage3             matlab.ui.container.Panel
        Panel_Damage2             matlab.ui.container.Panel
        Panel_Damage1             matlab.ui.container.Panel
        Tab_2DVisualization       matlab.ui.container.Tab
        Panel_2DVisualization     matlab.ui.container.Panel
        Grid_2DVisualization      matlab.ui.container.GridLayout
        Panel_Axial               matlab.ui.container.Panel
        Grid_Axial                matlab.ui.container.GridLayout
        Panel_AxialControls       matlab.ui.container.Panel
        Spinner_Axial             matlab.ui.control.Spinner
        CorteAxialLabel           matlab.ui.control.Label
        Slider_Axial              matlab.ui.control.Slider
        UIAxes_Axial              matlab.ui.control.UIAxes
        Panel_Sagittal            matlab.ui.container.Panel
        Grid_Sagittal             matlab.ui.container.GridLayout
        Panel_SagittalControls    matlab.ui.container.Panel
        Spinner_Sagittal          matlab.ui.control.Spinner
        CorteSagitalLabel         matlab.ui.control.Label
        Slider_Sagittal           matlab.ui.control.Slider
        UIAxes_Sagittal           matlab.ui.control.UIAxes
        Panel_Coronal             matlab.ui.container.Panel
        Grid_Coronal              matlab.ui.container.GridLayout
        Panel_CoronalControls     matlab.ui.container.Panel
        Spinner_Coronal           matlab.ui.control.Spinner
        CorteSagitalLabel_2       matlab.ui.control.Label
        Slider_Coronal            matlab.ui.control.Slider
        UIAxes_Coronal            matlab.ui.control.UIAxes
        Panel_Switches            matlab.ui.container.Panel
        Switch_Damage4            matlab.ui.control.Switch
        DaoTipo4Label             matlab.ui.control.Label
        Switch_Damage3            matlab.ui.control.Switch
        DaoTipo3Label             matlab.ui.control.Label
        Switch_Damage2            matlab.ui.control.Switch
        DaoTipo2Label             matlab.ui.control.Label
        Switch_Damage1            matlab.ui.control.Switch
        DaoTipo1SwitchLabel       matlab.ui.control.Label
        Tab_Information           matlab.ui.container.Tab
        Panel_Information         matlab.ui.container.Panel
        Grid_Information          matlab.ui.container.GridLayout
        Panel_StatusInformation   matlab.ui.container.Panel
        Lbl_Status                matlab.ui.control.Label
        Lamp_Status               matlab.ui.control.Lamp
        Image_StatusIcon          matlab.ui.control.Image
        Lbl_StatusTitle           matlab.ui.control.Label
        Panel_DensityInformation  matlab.ui.container.Panel
        Lbl_DensityDamage4        matlab.ui.control.Label
        Lbl_DensityDamage3        matlab.ui.control.Label
        Lbl_DensityDamage2        matlab.ui.control.Label
        Lbl_DensityDamage1        matlab.ui.control.Label
        Image_DensityIcon         matlab.ui.control.Image
        Lbl_DensityTitle          matlab.ui.control.Label
        Panel_VolumeInformation   matlab.ui.container.Panel
        Lbl_VolumeDamage4         matlab.ui.control.Label
        Lbl_VolumeDamage3         matlab.ui.control.Label
        Lbl_VolumeDamage2         matlab.ui.control.Label
        Lbl_VolumeDamage1         matlab.ui.control.Label
        Lbl_VolumeLungs           matlab.ui.control.Label
        Image_VolumeIcon          matlab.ui.control.Image
        Lbl_VolumeTitle           matlab.ui.control.Label
        Panel_Sidebar             matlab.ui.container.Panel
        Btn_ShowSegmentation      matlab.ui.control.Button
        Lbl_Logs                  matlab.ui.control.Label
        Btn_Start                 matlab.ui.control.Button
        Btn_LoadImage             matlab.ui.control.Button
        Lbl_Subtitle              matlab.ui.control.Label
        Lbl_Title                 matlab.ui.control.Label
        Image_Icon                matlab.ui.control.Image
        Panel_Splash              matlab.ui.container.Panel
        Lbl_SplashText            matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        
        CT
        CTGlobalMin = -550;
        CTGlobalMax = 200;

        lungs
        nRows
        nSlices

        damage1
        damage2
        damage3
        damage4
        
        Colors

    end
    
    methods (Access = private)
        
        function showLog(app, message)
            app.Lbl_Logs.Text = message;
            drawnow;
        end
        
        function updateGUIAfterImageLoad(app)
 
            app.Btn_Start.Enable = "on";
            app.Btn_ShowSegmentation.Enable = 'off';
            app.Lbl_SplashText.Text = "Bien, ahora da clic en el botón PROCESAR";
            drawnow;

        end

        function updateGUIBeforeProcessing(app)
            
            app.Btn_LoadImage.Enable = 'off';
            app.Btn_Start.Enable = 'off';
            
            app.Lbl_SplashText.Text = "Esto puede tardar un par de minutos, la pantalla se actualizará automáticamente cuando el proceso termine"; 

        end

        function initialize3DVisualizations(app)
            
            volshow(app.damage1.Image, 'Parent', app.Panel_Damage1);
            volshow(app.damage2.Image, 'Parent', app.Panel_Damage2);
            volshow(app.damage3.Image, 'Parent', app.Panel_Damage3);
            volshow(app.damage4.Image, 'Parent', app.Panel_Damage4);
        
        end

        function initialize2DVisualizations(app)
            
            initializeRenderingColors(app);
            initialize2DControls(app);
            render2DVisualizations(app);
        
        end

        function initializeRenderingColors(app)
            
            % Orange

            orangeAxial = zeros(app.nRows, app.nRows);
            orangeAxial(:, :, 1) = 0.8500;
            orangeAxial(:, :, 2) = 0.3250;
            orangeAxial(:, :, 3) = 0.0980;

            orangeNonAxial = zeros(app.nSlices, app.nRows, 3);
            orangeNonAxial(:, :, 1) = 0.8500;
            orangeNonAxial(:, :, 2) = 0.3250;
            orangeNonAxial(:, :, 3) = 0.0980;

            app.Colors.Orange.Axial = orangeAxial;
            app.Colors.Orange.NonAxial = orangeNonAxial;

            % Cyan
            
            cyanAxial = zeros(app.nRows, app.nRows);
            cyanAxial(:, :, 1) = 0.3010;
            cyanAxial(:, :, 2) = 0.7450;
            cyanAxial(:, :, 3) = 0.9330;

            cyanNonAxial = zeros(app.nSlices, app.nRows, 3);
            cyanNonAxial(:, :, 1) = 0.3010;
            cyanNonAxial(:, :, 2) = 0.7450;
            cyanNonAxial(:, :, 3) = 0.9330;

            app.Colors.Cyan.Axial = cyanAxial;
            app.Colors.Cyan.NonAxial = cyanNonAxial;

            % Green
            
            greenAxial = zeros(app.nRows, app.nRows);
            greenAxial(:, :, 1) = 0;
            greenAxial(:, :, 2) = 1;
            greenAxial(:, :, 3) = 0;

            greenNonAxial = zeros(app.nSlices, app.nRows, 3);
            greenNonAxial(:, :, 1) = 0;
            greenNonAxial(:, :, 2) = 1;
            greenNonAxial(:, :, 3) = 0;

            app.Colors.Green.Axial = greenAxial;
            app.Colors.Green.NonAxial = greenNonAxial;

            % Magenta
            
            magentaAxial = zeros(app.nRows, app.nRows);
            magentaAxial(:, :, 1) = 1;
            magentaAxial(:, :, 2) = 0;
            magentaAxial(:, :, 3) = 1;

            magentaNonAxial = zeros(app.nSlices, app.nRows, 3);
            magentaNonAxial(:, :, 1) = 1;
            magentaNonAxial(:, :, 2) = 0;
            magentaNonAxial(:, :, 3) = 1;

            app.Colors.Magenta.Axial = magentaAxial;
            app.Colors.Magenta.NonAxial = magentaNonAxial;


        end

        function render2DVisualizations(app)
            
            drawnow;

            axialCut = app.Spinner_Axial.Value;
            coronalCut = app.Spinner_Coronal.Value;
            sagittalCut = app.Spinner_Sagittal.Value;

            CTAxialSegmentation = app.CT(:, :, axialCut);
            CTCoronal = flip(permute(app.CT(:, coronalCut, :), [3 1 2]), 1);
            CTSagittal = flip(permute(app.CT(sagittalCut, :, :), [3 2 1]), 1);

            imshow(CTAxialSegmentation, [app.CTGlobalMin app.CTGlobalMax], "Parent", app.UIAxes_Axial);
            imshow(CTCoronal, [app.CTGlobalMin app.CTGlobalMax], "Parent", app.UIAxes_Coronal);
            imshow(CTSagittal, [app.CTGlobalMin app.CTGlobalMax], "Parent", app.UIAxes_Sagittal);
            
            showDamage1 = app.Switch_Damage1.Value == "On";
            showDamage2 = app.Switch_Damage2.Value == "On";
            showDamage3 = app.Switch_Damage3.Value == "On";
            showDamage4 = app.Switch_Damage4.Value == "On";
    
            
            if(showDamage1)
                
                CTAxialDamage1 = app.damage1.Image(:, :, axialCut);
                CTCoronalDamage1 = flip(permute(app.damage1.Image(:, coronalCut, :), [3 1 2]), 1);
                CTSagittalDamage1 = flip(permute(app.damage1.Image(sagittalCut, :, :), [3 2 1]), 1);
                
                hold(app.UIAxes_Axial, 'on');
                imAxial = imshow(app.Colors.Orange.Axial, "Parent", app.UIAxes_Axial);
                set(imAxial, "AlphaData", CTAxialDamage1(:,:,1));

                hold(app.UIAxes_Coronal, 'on');
                imCoronal = imshow(app.Colors.Orange.NonAxial, "Parent", app.UIAxes_Coronal);
                set(imCoronal, "AlphaData", CTCoronalDamage1(:,:,1));

                hold(app.UIAxes_Sagittal, 'on');
                imSagittal = imshow(app.Colors.Orange.NonAxial, "Parent", app.UIAxes_Sagittal);
                set(imSagittal, "AlphaData", CTSagittalDamage1(:,:,1));

            end

            if(showDamage2)
                
                CTAxialDamage2 = app.damage2.Image(:, :, axialCut);
                CTCoronalDamage2 = flip(permute(app.damage2.Image(:, coronalCut, :), [3 1 2]), 1);
                CTSagittalDamage2 = flip(permute(app.damage2.Image(sagittalCut, :, :), [3 2 1]), 1);
                
                hold(app.UIAxes_Axial, 'on');
                imAxial = imshow(app.Colors.Cyan.Axial, "Parent", app.UIAxes_Axial);
                set(imAxial, "AlphaData", CTAxialDamage2(:,:,1));
                
                hold(app.UIAxes_Coronal, 'on');
                imCoronal = imshow(app.Colors.Cyan.NonAxial, "Parent", app.UIAxes_Coronal);
                set(imCoronal, "AlphaData", CTCoronalDamage2(:,:,1));
                
                hold(app.UIAxes_Sagittal, 'on');
                imSagittal = imshow(app.Colors.Cyan.NonAxial, "Parent", app.UIAxes_Sagittal);
                set(imSagittal, "AlphaData", CTSagittalDamage2(:,:,1));

            end

            if(showDamage3)
                
                CTAxialDamage3 = app.damage3.Image(:, :, axialCut);
                CTCoronalDamage3 = flip(permute(app.damage3.Image(:, coronalCut, :), [3 1 2]), 1);
                CTSagittalDamage3 = flip(permute(app.damage3.Image(sagittalCut, :, :), [3 2 1]), 1);
                
                hold(app.UIAxes_Axial, 'on');
                imAxial = imshow(app.Colors.Green.Axial, "Parent", app.UIAxes_Axial);
                set(imAxial, "AlphaData", CTAxialDamage3(:,:,1));
                
                hold(app.UIAxes_Coronal, 'on');
                imCoronal = imshow(app.Colors.Green.NonAxial, "Parent", app.UIAxes_Coronal);
                set(imCoronal, "AlphaData", CTCoronalDamage3(:,:,1));
                
                hold(app.UIAxes_Sagittal, 'on');
                imSagittal = imshow(app.Colors.Green.NonAxial, "Parent", app.UIAxes_Sagittal);
                set(imSagittal, "AlphaData", CTSagittalDamage3(:,:,1));

            end

            if(showDamage4)
                
                CTAxialDamage4 = app.damage4.Image(:, :, axialCut);
                CTCoronalDamage4 = flip(permute(app.damage4.Image(:, coronalCut, :), [3 1 2]), 1);
                CTSagittalDamage4 = flip(permute(app.damage4.Image(sagittalCut, :, :), [3 2 1]), 1);
                
                hold(app.UIAxes_Axial, 'on');
                imAxial = imshow(app.Colors.Magenta.Axial, "Parent", app.UIAxes_Axial);
                set(imAxial, "AlphaData", CTAxialDamage4(:,:,1));
                
                hold(app.UIAxes_Coronal, 'on');
                imCoronal = imshow(app.Colors.Magenta.NonAxial, "Parent", app.UIAxes_Coronal);
                set(imCoronal, "AlphaData", CTCoronalDamage4(:,:,1));
                
                hold(app.UIAxes_Sagittal, 'on');
                imSagittal = imshow(app.Colors.Magenta.NonAxial, "Parent", app.UIAxes_Sagittal);
                set(imSagittal, "AlphaData", CTSagittalDamage4(:,:,1));

            end

            drawnow;
            
        end

        function initialize2DControls(app)
                
            axialLimits = [1 app.nSlices];
            nonAxialLimits = [1 app.nRows];

            app.Slider_Coronal.Limits = nonAxialLimits;
            app.Spinner_Coronal.Limits = nonAxialLimits;

            app.Slider_Sagittal.Limits = nonAxialLimits;
            app.Spinner_Sagittal.Limits = nonAxialLimits;

            app.Slider_Axial.Limits = axialLimits;
            app.Spinner_Axial.Limits = axialLimits;

        end

        function initializeInformationTab(app)
        
            app.Lbl_VolumeLungs.Text = "Pulmón: " + num2str(app.lungs.Volume) + " Litros";
            app.Lbl_VolumeDamage1.Text = "Daño Tipo 1: " + num2str(app.damage1.Volume) + " Litros";
            app.Lbl_VolumeDamage2.Text = "Daño Tipo 2: " + num2str(app.damage2.Volume) + " Litros";
            app.Lbl_VolumeDamage3.Text = "Daño Tipo 3: " + num2str(app.damage3.Volume) + " Litros";
            app.Lbl_VolumeDamage4.Text = "Daño Tipo 4: " + num2str(app.damage4.Volume) + " Litros";

            app.Lbl_DensityDamage1.Text = "Daño Tipo 1: " + num2str(app.damage1.Density) + " UH";
            app.Lbl_DensityDamage2.Text = "Daño Tipo 2: " + num2str(app.damage2.Density) + " UH";
            app.Lbl_DensityDamage3.Text = "Daño Tipo 3: " + num2str(app.damage3.Density) + " UH";
            app.Lbl_DensityDamage4.Text = "Daño Tipo 4: " + num2str(app.damage4.Density) + " UH";

            severity = getSeverity(app.lungs.Volume, [app.damage1.Volume app.damage2.Volume app.damage3.Volume app.damage4.Volume]);

            if(severity <= 15)
                app.Lamp_Status.Color = 'green';
                app.Lbl_Status.Text = 'AFECTACIÓN LEVE';
            elseif(severity > 15 && severity <= 45)
                app.Lamp_Status.Color = 'yellow';
                app.Lbl_Status.Text = 'AFECTACIÓN MODERADA';
            elseif(severity > 45)
                app.Lamp_Status.Color = 'red';
                app.Lbl_Status.Text = 'AFECTACIÓN SEVERA';
            end

        end

        function updateGUIAfterProcessing(app)
            
            showLog(app, "INICIALIZANDO CONTROLES 3D");
            initialize3DVisualizations(app);
            showLog(app, "INICIALIZANDO CONTROLES 2D");
            initialize2DVisualizations(app);
            showLog(app, "INICIALIZANDO PESTAÑA DE INFORMACIÓN");
            initializeInformationTab(app);

            app.Btn_LoadImage.Enable = 'on';
            app.Btn_ShowSegmentation.Enable = 'on';

            app.Panel_Splash.Visible = 'off';
            app.Panel_Visualizations.Visible = 'on';
            drawnow;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Btn_LoadImage
        function loadImage(app, event)
            
            [file, path] = uigetfile('*.nrrd');

            if(file == 0)
                return;
            end
            
            filePath = strcat(path, file);
            showLog(app, strcat("CARGANDO IMAGEN: ", file));
            app.CT = nrrdread(filePath);
            [app.nRows, ~, app.nSlices] = size(app.CT);
            showLog(app, strcat("IMAGEN CARGADA: ", file));
            updateGUIAfterImageLoad(app);
            

        end

        % Button pushed function: Btn_Start
        function covidSegmentation(app, event)
            
            updateGUIBeforeProcessing(app);

            showLog(app, "SEGMENTACIÓN DE PULMONES 0%");

            % Segmentación sin traquea funcional imagenes 1-5
            % app.lungs.Mask = segmentLungs(app.CT);

            % Segmentación con traquea funcional imagenes 1-5
            %app.lungs.Mask = segmentLungsV2(app.CT);

            % Segmentación especial para imágenes extra 1-2
            app.lungs.Mask = segmentLungsV3(app.CT);
            
            app.lungs.Image = imcomplement(app.lungs.Mask);
            showLog(app, "SEGMENTACIÓN DE PULMONES 100%");

            showLog(app, "SEGMENTACIÓN DE DAÑOS 1/4");
            app.damage1.Image = app.lungs.Mask < -901 & app.lungs.Mask > -1000;
            showLog(app, "SEGMENTACIÓN DE DAÑOS 2/4");
            app.damage2.Image = app.lungs.Mask < -100 & app.lungs.Mask > -500;
            showLog(app, "SEGMENTACIÓN DE DAÑOS 3/4");
            app.damage3.Image = app.lungs.Mask < -977 & app.lungs.Mask > -1024;
            showLog(app, "SEGMENTACIÓN DE DAÑOS 4/4");
            app.damage4.Image = app.lungs.Mask < -470 & app.lungs.Mask > -730;

%           damage4Props = regionprops3(app.damage4.Image, "Volume");
%           damage4Volumes = sort(damage4Props.Volume, 'descend');
%           app.damage4.Image = bwareaopen(app.damage4.Image, damage4Volumes(2, 1));

            showLog(app, "CÁLCULO DE VOLÚMENES Y DENSIDADES 0%");

            app.lungs.Volume = getVolume(app.lungs.Image);
            app.lungs.Density = getDensity(app.lungs.Image);

            showLog(app, "CÁLCULO DE VOLÚMENES Y DENSIDADES 20%");
            
            app.damage1.Volume = getVolume(app.damage1.Image);
            app.damage1.Density = getDensity(app.damage1.Image);

            showLog(app, "CÁLCULO DE VOLÚMENES Y DENSIDADES 40%");
            
            app.damage2.Volume = getVolume(app.damage2.Image);
            app.damage2.Density = getDensity(app.damage2.Image);

            showLog(app, "CÁLCULO DE VOLÚMENES Y DENSIDADES 60%");
            
            app.damage3.Volume = getVolume(app.damage3.Image);
            app.damage3.Density = getDensity(app.damage3.Image);

            showLog(app, "CÁLCULO DE VOLÚMENES Y DENSIDADES 80%");
            
            app.damage4.Volume = getVolume(app.damage4.Image);
            app.damage4.Density = getDensity(app.damage4.Image);

            showLog(app, "CÁLCULO DE VOLÚMENES Y DENSIDADES 100%");

            updateGUIAfterProcessing(app);

            showLog(app, "PROCESAMIENTO TERMINADO");

        end

        % Button pushed function: Btn_ShowSegmentation
        function showSegmentation(app, event)
            volumeViewer(app.lungs.Image);
        end

        % Value changed function: Slider_Coronal
        function SliderChange_Coronal(app, event)
            value = round(app.Slider_Coronal.Value);
            app.Spinner_Coronal.Value = value;
            render2DVisualizations(app);
        end

        % Value changed function: Spinner_Coronal
        function SpinnerChange_Coronal(app, event)
            value = round(app.Spinner_Coronal.Value);
            app.Slider_Coronal.Value = value;
            render2DVisualizations(app);
        end

        % Value changed function: Slider_Sagittal
        function SliderChange_Sagittal(app, event)
            value = round(app.Slider_Sagittal.Value);
            app.Spinner_Sagittal.Value = value;
            render2DVisualizations(app);
        end

        % Value changed function: Spinner_Sagittal
        function SpinnerChange_Sagittal(app, event)
            value = round(app.Spinner_Sagittal.Value);
            app.Slider_Sagittal.Value = value;
            render2DVisualizations(app);
        end

        % Value changed function: Slider_Axial
        function SliderChange_Axial(app, event)
            value = round(app.Slider_Axial.Value);
            app.Spinner_Axial.Value = value;
            render2DVisualizations(app);
        end

        % Value changed function: Spinner_Axial
        function SpinnerChange_Axial(app, event)
            value = round(app.Spinner_Axial.Value);
            app.Slider_Axial.Value = value;
            render2DVisualizations(app);
        end

        % Value changed function: Switch_Damage1, Switch_Damage2, 
        % ...and 2 other components
        function Switch_Damage(app, event)
            render2DVisualizations(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure_App = uifigure('Visible', 'off');
            app.UIFigure_App.Name = 'Proyecto COVID-19';
            app.UIFigure_App.Icon = fullfile(pathToMLAPP, 'covidIcon.png');
            app.Panel_Splash = uipanel(app.UIFigure_App);
            app.Lbl_SplashText = uilabel(app.Panel_Splash);
            app.Lbl_SplashText.Text = 'Para comenzar, carga una imagen NRRD';
            app.Panel_Sidebar = uipanel(app.UIFigure_App);
            app.Image_Icon = uiimage(app.Panel_Sidebar);
            app.Image_Icon.ImageSource = fullfile(pathToMLAPP, 'covidIcon.png');
            app.Lbl_Title = uilabel(app.Panel_Sidebar);
            app.Lbl_Title.Text = 'Proyecto COVID-19';
            app.Lbl_Subtitle = uilabel(app.Panel_Sidebar);
            app.Lbl_Subtitle.Text = 'EQUIPO 3 - IAIM -CUCEI';
            app.Btn_LoadImage = uibutton(app.Panel_Sidebar, 'push');
            app.Btn_LoadImage.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.Btn_LoadImage.Text = 'CARGAR IMAGEN';
            app.Btn_Start = uibutton(app.Panel_Sidebar, 'push');
            app.Btn_Start.ButtonPushedFcn = createCallbackFcn(app, @covidSegmentation, true);
            app.Btn_Start.Enable = 'off';
            app.Btn_Start.Text = 'PROCESAR';
            app.Lbl_Logs = uilabel(app.Panel_Sidebar);
            app.Lbl_Logs.Text = '';
            app.Btn_ShowSegmentation = uibutton(app.Panel_Sidebar, 'push');
            app.Btn_ShowSegmentation.ButtonPushedFcn = createCallbackFcn(app, @showSegmentation, true);
            app.Btn_ShowSegmentation.Enable = 'off';
            app.Btn_ShowSegmentation.Text = 'VER SEGMENTACIÓN';
            app.Panel_Visualizations = uipanel(app.UIFigure_App);
            app.Panel_Visualizations.Visible = 'off';
            app.TabGroup = uitabgroup(app.Panel_Visualizations);
            app.Tab_3DVisualization = uitab(app.TabGroup);
            app.Tab_3DVisualization.Title = '3D';
            app.Panel_3DVisualization = uipanel(app.Tab_3DVisualization);
            app.Grid_3DVisualization = uigridlayout(app.Panel_3DVisualization);
            app.Panel_Damage1 = uipanel(app.Grid_3DVisualization);
            app.Panel_Damage1.Title = 'Daño Tipo 1';
            app.Panel_Damage2 = uipanel(app.Grid_3DVisualization);
            app.Panel_Damage2.Title = 'Daño Tipo 2';
            app.Panel_Damage3 = uipanel(app.Grid_3DVisualization);
            app.Panel_Damage3.Title = 'Daño Tipo 3';
            app.Panel_Damage4 = uipanel(app.Grid_3DVisualization);
            app.Panel_Damage4.Title = 'Daño Tipo 4';
            app.Tab_2DVisualization = uitab(app.TabGroup);
            app.Tab_2DVisualization.Title = '2D';
            app.Panel_2DVisualization = uipanel(app.Tab_2DVisualization);
            app.Grid_2DVisualization = uigridlayout(app.Panel_2DVisualization);
            app.Panel_Switches = uipanel(app.Grid_2DVisualization);
            app.Panel_Switches.Title = 'Render';
            app.DaoTipo1SwitchLabel = uilabel(app.Panel_Switches);
            app.DaoTipo1SwitchLabel.Text = 'Daño Tipo 1';
            app.Switch_Damage1 = uiswitch(app.Panel_Switches, 'slider');
            app.Switch_Damage1.ValueChangedFcn = createCallbackFcn(app, @Switch_Damage, true);
            app.DaoTipo2Label = uilabel(app.Panel_Switches);
            app.DaoTipo2Label.Text = 'Daño Tipo 2';
            app.Switch_Damage2 = uiswitch(app.Panel_Switches, 'slider');
            app.Switch_Damage2.ValueChangedFcn = createCallbackFcn(app, @Switch_Damage, true);
            app.DaoTipo3Label = uilabel(app.Panel_Switches);
            app.DaoTipo3Label.Text = 'Daño Tipo 3';
            app.Switch_Damage3 = uiswitch(app.Panel_Switches, 'slider');
            app.Switch_Damage3.ValueChangedFcn = createCallbackFcn(app, @Switch_Damage, true);
            app.DaoTipo4Label = uilabel(app.Panel_Switches);
            app.DaoTipo4Label.Text = 'Daño Tipo 4';
            app.Switch_Damage4 = uiswitch(app.Panel_Switches, 'slider');
            app.Switch_Damage4.ValueChangedFcn = createCallbackFcn(app, @Switch_Damage, true);
            app.Panel_Coronal = uipanel(app.Grid_2DVisualization);
            app.Grid_Coronal = uigridlayout(app.Panel_Coronal);
            app.UIAxes_Coronal = uiaxes(app.Grid_Coronal);
            app.UIAxes_Coronal.XTick = [];
            app.UIAxes_Coronal.YTick = [];
            app.UIAxes_Coronal.ZTick = [];
            app.Panel_CoronalControls = uipanel(app.Grid_Coronal);
            app.Slider_Coronal = uislider(app.Panel_CoronalControls);
            app.Slider_Coronal.ValueChangedFcn = createCallbackFcn(app, @SliderChange_Coronal, true);
            app.CorteSagitalLabel_2 = uilabel(app.Panel_CoronalControls);
            app.CorteSagitalLabel_2.Text = 'Corte Sagital';
            app.Spinner_Coronal = uispinner(app.Panel_CoronalControls);
            app.Spinner_Coronal.RoundFractionalValues = 'on';
            app.Spinner_Coronal.ValueChangedFcn = createCallbackFcn(app, @SpinnerChange_Coronal, true);
            app.Panel_Sagittal = uipanel(app.Grid_2DVisualization);
            app.Grid_Sagittal = uigridlayout(app.Panel_Sagittal);
            app.UIAxes_Sagittal = uiaxes(app.Grid_Sagittal);
            app.UIAxes_Sagittal.XTick = [];
            app.UIAxes_Sagittal.YTick = [];
            app.UIAxes_Sagittal.ZTick = [];
            app.Panel_SagittalControls = uipanel(app.Grid_Sagittal);
            app.Slider_Sagittal = uislider(app.Panel_SagittalControls);
            app.Slider_Sagittal.ValueChangedFcn = createCallbackFcn(app, @SliderChange_Sagittal, true);
            app.CorteSagitalLabel = uilabel(app.Panel_SagittalControls);
            app.CorteSagitalLabel.Text = 'Corte Coronal';
            app.Spinner_Sagittal = uispinner(app.Panel_SagittalControls);
            app.Spinner_Sagittal.RoundFractionalValues = 'on';
            app.Spinner_Sagittal.ValueChangedFcn = createCallbackFcn(app, @SpinnerChange_Sagittal, true);
            app.Panel_Axial = uipanel(app.Grid_2DVisualization);
            app.Grid_Axial = uigridlayout(app.Panel_Axial);
            app.UIAxes_Axial = uiaxes(app.Grid_Axial);
            app.UIAxes_Axial.XTick = [];
            app.UIAxes_Axial.YTick = [];
            app.UIAxes_Axial.ZTick = [];
            app.Panel_AxialControls = uipanel(app.Grid_Axial);
            app.Slider_Axial = uislider(app.Panel_AxialControls);
            app.Slider_Axial.ValueChangedFcn = createCallbackFcn(app, @SliderChange_Axial, true);
            app.CorteAxialLabel = uilabel(app.Panel_AxialControls);
            app.CorteAxialLabel.Text = 'Corte Axial';
            app.Spinner_Axial = uispinner(app.Panel_AxialControls);
            app.Spinner_Axial.RoundFractionalValues = 'on';
            app.Spinner_Axial.ValueChangedFcn = createCallbackFcn(app, @SpinnerChange_Axial, true);
            app.Tab_Information = uitab(app.TabGroup);
            app.Tab_Information.Title = 'Información';
            app.Panel_Information = uipanel(app.Tab_Information);
            app.Grid_Information = uigridlayout(app.Panel_Information);
            app.Panel_VolumeInformation = uipanel(app.Grid_Information);
            app.Lbl_VolumeTitle = uilabel(app.Panel_VolumeInformation);
            app.Lbl_VolumeTitle.Text = 'Volumen';
            app.Image_VolumeIcon = uiimage(app.Panel_VolumeInformation);
            app.Image_VolumeIcon.ImageSource = fullfile(pathToMLAPP, 'lungsIcon.png');
            app.Lbl_VolumeLungs = uilabel(app.Panel_VolumeInformation);
            app.Lbl_VolumeLungs.Text = 'Pulmón: N/D';
            app.Lbl_VolumeDamage1 = uilabel(app.Panel_VolumeInformation);
            app.Lbl_VolumeDamage1.Text = 'Daño Tipo 1: N/D';
            app.Lbl_VolumeDamage2 = uilabel(app.Panel_VolumeInformation);
            app.Lbl_VolumeDamage2.Text = 'Daño Tipo 2: N/D';
            app.Lbl_VolumeDamage3 = uilabel(app.Panel_VolumeInformation);
            app.Lbl_VolumeDamage3.Text = 'Daño Tipo 3: N/D';
            app.Lbl_VolumeDamage4 = uilabel(app.Panel_VolumeInformation);
            app.Lbl_VolumeDamage4.Text = 'Daño Tipo 4: N/D';
            app.Panel_DensityInformation = uipanel(app.Grid_Information);
            app.Lbl_DensityTitle = uilabel(app.Panel_DensityInformation);
            app.Lbl_DensityTitle.Text = 'Densidad';
            app.Image_DensityIcon = uiimage(app.Panel_DensityInformation);
            app.Image_DensityIcon.ImageSource = fullfile(pathToMLAPP, 'glassIcon.png');
            app.Lbl_DensityDamage1 = uilabel(app.Panel_DensityInformation);
            app.Lbl_DensityDamage1.Text = 'Daño Tipo 1: N/D';
            app.Lbl_DensityDamage2 = uilabel(app.Panel_DensityInformation);
            app.Lbl_DensityDamage2.Text = 'Daño Tipo 2: N/D';
            app.Lbl_DensityDamage3 = uilabel(app.Panel_DensityInformation);
            app.Lbl_DensityDamage3.Text = 'Daño Tipo 3: N/D';
            app.Lbl_DensityDamage4 = uilabel(app.Panel_DensityInformation);
            app.Lbl_DensityDamage4.Text = 'Daño Tipo 4: N/D';
            app.Panel_StatusInformation = uipanel(app.Grid_Information);
            app.Lbl_StatusTitle = uilabel(app.Panel_StatusInformation);
            app.Lbl_StatusTitle.Text = 'Indicador de Afectación';
            app.Image_StatusIcon = uiimage(app.Panel_StatusInformation);
            app.Image_StatusIcon.ImageSource = fullfile(pathToMLAPP, 'trafficLigthIcon.png');
            app.Lamp_Status = uilamp(app.Panel_StatusInformation);
            app.Lbl_Status = uilabel(app.Panel_StatusInformation);
            app.Lbl_Status.Text = 'N/D';
            % Show the figure after all components are created
            app.UIFigure_App.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PF_IAIM_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_App)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_App)
        end
    end
end